classdef digitpredict < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        HANDWRITENDIGITPREDICTORLabel   matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        DigitPredictedFromImageisLabel  matlab.ui.control.Label
        chooseimagefileButton           matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: chooseimagefileButton
        function chooseimagefileButtonPushed(app, event)
            [filename,pathname]=uigetfile('*.*','pick an image');
            filename=strcat(pathname,filename);
            a=imread(filename);
            d=uiprogressdlg(app.UIFigure,'Title','Please wait','Message','processing');
            imshow(a,'parent',app.UIAxes);
            
            [Theta1,Theta2]=test_train();
            a_grey=im2gray(a);
            c=imresize(a_grey,[20,20]);
            c=im2double(c);
            c1=reshape(c,[1,400]);
            p=predict(Theta1,Theta2,c1);
            close(d);
            p=mod(p,10);
            h=num2str(p);
            app.Label.Text=h;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'image choosen')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.chooseimagefileButton = uibutton(app.UIFigure, 'push');
            app.chooseimagefileButton.ButtonPushedFcn = createCallbackFcn(app, @chooseimagefileButtonPushed, true);
            app.chooseimagefileButton.Text = 'choose image file';
            app.DigitPredictedFromImageisLabel = uilabel(app.UIFigure);
            app.DigitPredictedFromImageisLabel.Text = 'Digit Predicted From Image is';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.HANDWRITENDIGITPREDICTORLabel = uilabel(app.UIFigure);
            app.HANDWRITENDIGITPREDICTORLabel.Text = 'HANDWRITEN DIGIT PREDICTOR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = digitpredict

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end